classdef String_vibrations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        label_speed              matlab.ui.control.Label
        speed_val                matlab.ui.control.NumericEditField
        label_left_border        matlab.ui.control.Label
        left_border_val          matlab.ui.control.NumericEditField
        label_right_border       matlab.ui.control.Label
        right_border_val         matlab.ui.control.NumericEditField
        label_time_begin         matlab.ui.control.Label
        time_begin_val           matlab.ui.control.NumericEditField
        label_time_end           matlab.ui.control.Label
        time_end_val             matlab.ui.control.NumericEditField
        label_discretization     matlab.ui.control.Label
        discretization_val       matlab.ui.control.NumericEditField
        label_initial_position   matlab.ui.control.Label
        initial_position_val     matlab.ui.control.EditField
        label_initial_speed      matlab.ui.control.Label
        initial_speed_val        matlab.ui.control.EditField
        label_force              matlab.ui.control.Label
        force                    matlab.ui.control.EditField
        label_left_boundary      matlab.ui.control.Label
        left_boundary_function   matlab.ui.control.EditField
        left_boundary_type       matlab.ui.container.ButtonGroup
        left_boundary_position   matlab.ui.control.RadioButton
        left_boundary_speed      matlab.ui.control.RadioButton
        right_boundary_type      matlab.ui.container.ButtonGroup
        right_boundary_position  matlab.ui.control.RadioButton
        right_boundary_speed     matlab.ui.control.RadioButton
        label_right_boundary     matlab.ui.control.Label
        right_boundary_function  matlab.ui.control.EditField
        button_calculate         matlab.ui.control.Button
        RightPanel               matlab.ui.container.Panel
        axes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: button_calculate
        function button_calculateButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure, "Title", "Ожидайте" , ...
                "Message", "Идёт расчёт...");
            
            %% параметры
            x_lim = [app.left_border_val.Value app.right_border_val.Value];  % границы пространства
            t_lim = [app.time_begin_val.Value app.time_end_val.Value]; % границы по времени
            c = app.speed_val.Value;          % скорость звука
            dx = app.discretization_val.Value;     % пространственный шаг
            
            % внешняя сила
            external_force = str2func("@(x, t) " + app.initial_position_val.Value);
            % начальное значение
            init_value = str2func("@(x) " + app.initial_position_val.Value);
            % начальная скорость
            init_speed = str2func("@(x) " + app.initial_speed_val.Value);
            
            % граничные условия
            left_constrain = str2func("@(t) " + app.left_boundary_function.Value);
            right_constrain = str2func("@(t) " + app.right_boundary_function.Value);
            
            %% расчет переменных
            dt = dx / c;                % шаг по времени
            x = x_lim(1):dx:x_lim(2);   % вектор значений коордитнаты
            t = t_lim(1):dt:t_lim(2);   % вектор значений времени
            
            % создание массива для решения
            string = zeros(length(t), length(x));
            
            % начальные условия
            string(1, :) = init_value(x);
            string(2, :) = string(1, :) + dt * init_speed(x);
            
            % граничные условия
            if (app.left_boundary_position.Value)
                string(:, 1) = left_constrain(t);
            elseif (app.left_boundary_speed.Value)
                for i = 2:length(t)
                    string(i, 1) = string(i - 1, 1) + ...
                        dt * left_constrain(t(i));
                end
            end

            if (app.right_boundary_position.Value)
                string(:, end) = right_constrain(t);
            elseif (app.right_boundary_speed.Value)
                for i = 2:length(t)
                    string(i, end) = string(i - 1, end) + ...
                        dt * right_constrain(t(i));
                end
            end


            % график начальных условий
            % plot(x, String(1, :), "LineWidth", 2)
            
            %% расчёт в цикле
            tic
            for i = 3:size(string, 1)
                for j = 2:(size(string, 2) - 1)
                    string(i, j) = string(i - 1, j - 1) + string(i - 1, j + 1) - ...
                        string(i - 2, j) + dt^2 * external_force(x(j), t(i));
                end
                d.Value = i / size(string, 1);
            end
            toc

            d.Message = "Построение графика...";
            
            %% график всего
            [X, T] = meshgrid(x, t);
            ax = app.axes;
            contour(ax, X, T, string, "Fill", "on", "LineColor", "k", "LineWidth", 1);
            xlabel(ax, "x, м");
            ylabel(ax, "t, c");
            c = colorbar(ax);
            c.Label.String = "y, м";

            close(d)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {651, 651};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {368, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.button_calculate = uibutton(app.LeftPanel, 'push');
            app.button_calculate.ButtonPushedFcn = createCallbackFcn(app, @button_calculateButtonPushed, true);
            app.button_calculate.Text = 'Расчитать';
            app.right_boundary_function = uieditfield(app.LeftPanel, 'text');
            app.right_boundary_function.Value = '0';
            app.label_right_boundary = uilabel(app.LeftPanel);
            app.label_right_boundary.Text = 'Граничное условие справа';
            app.right_boundary_type = uibuttongroup(app.LeftPanel);
            app.right_boundary_type.Title = 'Тип граничного условия справа';
            app.right_boundary_speed = uiradiobutton(app.right_boundary_type);
            app.right_boundary_speed.Text = 'Скорость';
            app.right_boundary_position = uiradiobutton(app.right_boundary_type);
            app.right_boundary_position.Text = 'Положение';
            app.right_boundary_position.Value = true;
            app.left_boundary_type = uibuttongroup(app.LeftPanel);
            app.left_boundary_type.Title = 'Тип граничного условия слева';
            app.left_boundary_speed = uiradiobutton(app.left_boundary_type);
            app.left_boundary_speed.Text = 'Скорость';
            app.left_boundary_position = uiradiobutton(app.left_boundary_type);
            app.left_boundary_position.Text = 'Положение';
            app.left_boundary_position.Value = true;
            app.left_boundary_function = uieditfield(app.LeftPanel, 'text');
            app.left_boundary_function.Value = '0';
            app.label_left_boundary = uilabel(app.LeftPanel);
            app.label_left_boundary.Text = 'Граничное условие слева';
            app.force = uieditfield(app.LeftPanel, 'text');
            app.force.Value = '0';
            app.label_force = uilabel(app.LeftPanel);
            app.label_force.Text = 'Сила f(x, t)';
            app.initial_speed_val = uieditfield(app.LeftPanel, 'text');
            app.initial_speed_val.Value = '0';
            app.label_initial_speed = uilabel(app.LeftPanel);
            app.label_initial_speed.Text = 'Начальная скорость u''(x)';
            app.initial_position_val = uieditfield(app.LeftPanel, 'text');
            app.initial_position_val.Value = 'sin(pi*x)';
            app.label_initial_position = uilabel(app.LeftPanel);
            app.label_initial_position.Text = 'Начальное положение u(x)';
            app.discretization_val = uieditfield(app.LeftPanel, 'numeric');
            app.discretization_val.Value = 0.001;
            app.label_discretization = uilabel(app.LeftPanel);
            app.label_discretization.Text = 'Шаг дискретизации по длине';
            app.time_end_val = uieditfield(app.LeftPanel, 'numeric');
            app.time_end_val.Value = 1;
            app.label_time_end = uilabel(app.LeftPanel);
            app.label_time_end.Text = 'Конечный момент времени';
            app.time_begin_val = uieditfield(app.LeftPanel, 'numeric');
            app.label_time_begin = uilabel(app.LeftPanel);
            app.label_time_begin.Text = 'Начальный момент времени';
            app.right_border_val = uieditfield(app.LeftPanel, 'numeric');
            app.right_border_val.Value = 1;
            app.label_right_border = uilabel(app.LeftPanel);
            app.label_right_border.Text = 'Правая граница';
            app.left_border_val = uieditfield(app.LeftPanel, 'numeric');
            app.label_left_border = uilabel(app.LeftPanel);
            app.label_left_border.Text = 'Левая граница';
            app.speed_val = uieditfield(app.LeftPanel, 'numeric');
            app.speed_val.Limits = [0 Inf];
            app.speed_val.Value = 1;
            app.label_speed = uilabel(app.LeftPanel);
            app.label_speed.Text = 'Скорость звука';
            app.RightPanel = uipanel(app.GridLayout);
            app.axes = uiaxes(app.RightPanel);
            title(app.axes, 'Title')
            xlabel(app.axes, 'X')
            ylabel(app.axes, 'Y')
            zlabel(app.axes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = String_vibrations

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end